classdef houghViz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        rotSliderLabel               matlab.ui.control.Label
        RotSlider                    matlab.ui.control.Slider
        xtransSliderLabel            matlab.ui.control.Label
        XTransSlider                 matlab.ui.control.Slider
        ytransSliderLabel            matlab.ui.control.Label
        YTransSlider                 matlab.ui.control.Slider
        HistogramAxes                matlab.ui.control.UIAxes
        ImgAxes                      matlab.ui.control.UIAxes
        SelectImageDropDownLabel     matlab.ui.control.Label
        SelectImageDropDown          matlab.ui.control.DropDown
        ResetTransformValuesButton   matlab.ui.control.Button
        FillValueEditFieldLabel      matlab.ui.control.Label
        FillValueEditField           matlab.ui.control.NumericEditField
        BoarderEditFieldLabel        matlab.ui.control.Label
        BoarderEditField             matlab.ui.control.NumericEditField
        xdimEditFieldLabel           matlab.ui.control.Label
        XDimEditField                matlab.ui.control.NumericEditField
        ydimEditFieldLabel           matlab.ui.control.Label
        YDimEditField                matlab.ui.control.NumericEditField
        ApplynewdimensionsButton     matlab.ui.control.Button
        ViewButtonGroup              matlab.ui.container.ButtonGroup
        RGBButton                    matlab.ui.control.RadioButton
        EdgeButton                   matlab.ui.control.RadioButton
        EdgeLineSegButton            matlab.ui.control.RadioButton
        MaxhoughpeaksEditFieldLabel  matlab.ui.control.Label
        MaxhoughpeaksEditField       matlab.ui.control.NumericEditField
        InPlaceGraphAxes             matlab.ui.control.UIAxes
        nBinsEditFieldLabel          matlab.ui.control.Label
        NBinsEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        imgs % Description
        imgShowOrig % Description
        transVec = [0, 0]; % Description
        thetaSliderValRad % Description
        rotMat = eye(3); % Description
        imgShow % Description
        desiredImgDims = [500, 500]; % Description
        fillValue = 255; % Description
        boarderSz = 0; % Description
        edgeThresholdCanny = 0.4; % Description
        distThreshold = 100;
        maxNodes = 10; % Description
        normThetaRho % Description
        shiftedRho % Description
        grph % Description
        thetaRho % Description
        eigenValues % Description
        eigenHist % Description
        nBins = 20; % Description
    end
    
    methods (Access = private)
        
        function updatePlots(app)
            % Apply Transformation
            app.imgShow = applyRotTrans(app.imgShowOrig, app.transVec, ...
                app.rotMat, app.fillValue);
            
            % Imshow
            selectedButton = app.ViewButtonGroup.SelectedObject;
            if selectedButton == app.RGBButton
                imshow(app.imgShow, 'Parent', app.ImgAxes);
            elseif selectedButton == app.EdgeButton
                edge_img = edge(app.imgShow, 'Canny', app.edgeThresholdCanny);
                imshow(edge_img, 'Parent', app.ImgAxes);
            elseif selectedButton == app.EdgeLineSegButton
                % TODO: add Hough line segments
                edge_img = edge(app.imgShow, 'Canny', app.edgeThresholdCanny);
                imshow(edge_img, 'Parent', app.ImgAxes);
            end
            
            % Make graph and return conventional and normalized Hough values
            [app.grph, app.thetaRho] = image2Graph(app.imgShow, ...
                app.maxNodes, app.distThreshold);
            
            % In-place graph plot
            plot(app.grph, 'XData', app.thetaRho(:, 1), 'YData', ...
                app.thetaRho(:, 2), 'Parent', app.InPlaceGraphAxes)
            
            % Calculate eigenvalues of normalized laplacian
            app.eigenValues = getEigenVals(app.grph);
            
            % Calculate the histogram vector (not used for plotting)
            % app.eigenHist = makeHistVecOn0to2(app.eigenValues, app.nBins);
            
            % Plot histogram of eigenvalues
            histogram(app.HistogramAxes, app.eigenValues, app.nBins);
        end
        
        function loadInImgs(app)
            [app.imgs, lbls] = loadImgsLblsStdSz("data", {'*.jpg', '*.png'}, ...
                app.desiredImgDims);
            
            dropDownLbls = cell(length(lbls), 1);
            for k = 1:length(lbls)
                dropDownLbls{k} = removeFileExtension(lbls{k});
            end
            
            app.SelectImageDropDown.Items = dropDownLbls;
            values = cell(length(lbls), 1);
            counter = 1;
            for k = 1:length(lbls)
                values{k} = counter;
                counter = counter + 1;
            end
            app.SelectImageDropDown.ItemsData = values;
            value = app.SelectImageDropDown.Value;
            app.imgShowOrig = squeeze(app.imgs(value, :, :, :));
        end
        
        function applyRotToTform(app)
            cos_value = cos(app.thetaSliderValRad);
            sin_value = sin(app.thetaSliderValRad);
            app.rotMat(1, 1) = cos_value;
            app.rotMat(1, 2) = -sin_value;
            app.rotMat(2, 1) = sin_value;
            app.rotMat(2, 2) = cos_value;
        end
        
        function updateSliderRanges(app)
            app.XTransSlider.Limits = [-app.desiredImgDims(2) - app.boarderSz, ...
                app.desiredImgDims(2) + app.boarderSz];
            app.YTransSlider.Limits = [-app.desiredImgDims(1) - app.boarderSz, ...
                app.desiredImgDims(1) + app.boarderSz];
        end
        
        function changeBoarder(app)
            selected = app.SelectImageDropDown.Value;
            app.imgShowOrig = addImgBoarder(squeeze(app.imgs(selected, :, :, :)), ...
                app.boarderSz, app.fillValue);
            app.updateSliderRanges();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(fullfile(cd, "src")));
            app.nBins = app.NBinsEditField.Value;
            app.loadInImgs();
            app.updateSliderRanges();
            app.FillValueEditField.Value = app.fillValue;
            app.MaxhoughpeaksEditField.Value = app.maxNodes;
            app.XDimEditField.Value = app.desiredImgDims(2);
            app.YDimEditField.Value = app.desiredImgDims(1);
            app.updatePlots();
        end

        % Value changed function: RotSlider
        function RotSliderValueChanged(app, event)
            app.thetaSliderValRad = (event.Value * pi) / 180;
            app.applyRotToTform();
            app.updatePlots();
        end

        % Value changed function: SelectImageDropDown
        function SelectImageDropDownValueChanged(app, event)
            value = app.SelectImageDropDown.Value;
            app.imgShowOrig = squeeze(app.imgs(value, :, :, :));
            app.changeBoarder();
            app.updatePlots();
        end

        % Value changed function: XTransSlider
        function XTransSliderValueChanged(app, event)
            app.transVec(1) = app.XTransSlider.Value;
            app.updatePlots();
        end

        % Value changed function: YTransSlider
        function YTransSliderValueChanged(app, event)
            app.transVec(2) = app.YTransSlider.Value;
            app.updatePlots();
        end

        % Value changing function: RotSlider
        function RotSliderValueChanging(app, event)
            app.thetaSliderValRad = (event.Value * pi) / 180;
            app.applyRotToTform();
            app.updatePlots;
        end

        % Value changing function: XTransSlider
        function XTransSliderValueChanging(app, event)
            app.transVec(1) = event.Value;
            app.updatePlots();
        end

        % Value changing function: YTransSlider
        function YTransSliderValueChanging(app, event)
            app.transVec(2) = event.Value;
            app.updatePlots();
        end

        % Value changed function: FillValueEditField
        function FillValueEditFieldValueChanged(app, event)
            app.fillValue = app.FillValueEditField.Value;
            app.updatePlots();
        end

        % Button pushed function: ResetTransformValuesButton
        function ResetTransformValuesButtonPushed(app, event)
            app.thetaSliderValRad = 0;
            app.applyRotToTform();
            app.transVec = [0, 0];
            app.RotSlider.Value = 0;
            app.XTransSlider.Value = 0;
            app.YTransSlider.Value = 0;
            app.updatePlots();
        end

        % Value changed function: BoarderEditField
        function BoarderEditFieldValueChanged(app, event)
            app.boarderSz = app.BoarderEditField.Value;
            app.changeBoarder();
            app.updatePlots();
        end

        % Button pushed function: ApplynewdimensionsButton
        function ApplynewdimensionsButtonPushed(app, event)
            app.desiredImgDims = [app.YDimEditField.Value, app.XDimEditField.Value];
            app.loadInImgs();
            app.changeBoarder();
            app.updatePlots();
        end

        % Selection changed function: ViewButtonGroup
        function ViewButtonGroupSelectionChanged(app, event)
            app.updatePlots();
        end

        % Value changed function: MaxhoughpeaksEditField
        function MaxhoughpeaksEditFieldValueChanged(app, event)
            app.maxNodes = app.MaxhoughpeaksEditField.Value;
            app.updatePlots();
        end

        % Value changed function: NBinsEditField
        function NBinsEditFieldValueChanged(app, event)
            app.nBins = app.NBinsEditField.Value;
            app.updatePlots();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.rotSliderLabel = uilabel(app.UIFigure);
            app.rotSliderLabel.Text = 'rot';
            app.RotSlider = uislider(app.UIFigure);
            app.RotSlider.Limits = [-180 180];
            app.RotSlider.ValueChangedFcn = createCallbackFcn(app, @RotSliderValueChanged, true);
            app.RotSlider.ValueChangingFcn = createCallbackFcn(app, @RotSliderValueChanging, true);
            app.xtransSliderLabel = uilabel(app.UIFigure);
            app.xtransSliderLabel.Text = 'x-trans';
            app.XTransSlider = uislider(app.UIFigure);
            app.XTransSlider.Limits = [-1 1];
            app.XTransSlider.ValueChangedFcn = createCallbackFcn(app, @XTransSliderValueChanged, true);
            app.XTransSlider.ValueChangingFcn = createCallbackFcn(app, @XTransSliderValueChanging, true);
            app.ytransSliderLabel = uilabel(app.UIFigure);
            app.ytransSliderLabel.Text = 'y-trans';
            app.YTransSlider = uislider(app.UIFigure);
            app.YTransSlider.ValueChangedFcn = createCallbackFcn(app, @YTransSliderValueChanged, true);
            app.YTransSlider.ValueChangingFcn = createCallbackFcn(app, @YTransSliderValueChanging, true);
            app.HistogramAxes = uiaxes(app.UIFigure);
            title(app.HistogramAxes, 'Eigenvalues of Laplacian')
            xlabel(app.HistogramAxes, 'value')
            ylabel(app.HistogramAxes, 'count')
            app.HistogramAxes.XLim = [0 2];
            app.ImgAxes = uiaxes(app.UIFigure);
            title(app.ImgAxes, 'Image')
            xlabel(app.ImgAxes, '')
            ylabel(app.ImgAxes, '')
            app.ImgAxes.XTick = [];
            app.ImgAxes.YTick = [];
            app.SelectImageDropDownLabel = uilabel(app.UIFigure);
            app.SelectImageDropDownLabel.Text = 'Select Image';
            app.SelectImageDropDown = uidropdown(app.UIFigure);
            app.SelectImageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectImageDropDownValueChanged, true);
            app.ResetTransformValuesButton = uibutton(app.UIFigure, 'push');
            app.ResetTransformValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTransformValuesButtonPushed, true);
            app.ResetTransformValuesButton.Text = 'Reset Transform Values';
            app.FillValueEditFieldLabel = uilabel(app.UIFigure);
            app.FillValueEditFieldLabel.Text = 'Fill Value';
            app.FillValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.FillValueEditField.Limits = [0 255];
            app.FillValueEditField.ValueDisplayFormat = '%.0f';
            app.FillValueEditField.ValueChangedFcn = createCallbackFcn(app, @FillValueEditFieldValueChanged, true);
            app.FillValueEditField.Value = 255;
            app.BoarderEditFieldLabel = uilabel(app.UIFigure);
            app.BoarderEditFieldLabel.Text = 'Boarder';
            app.BoarderEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoarderEditField.Limits = [0 1000];
            app.BoarderEditField.RoundFractionalValues = 'on';
            app.BoarderEditField.ValueDisplayFormat = '%.0f';
            app.BoarderEditField.ValueChangedFcn = createCallbackFcn(app, @BoarderEditFieldValueChanged, true);
            app.xdimEditFieldLabel = uilabel(app.UIFigure);
            app.xdimEditFieldLabel.Text = 'x dim';
            app.XDimEditField = uieditfield(app.UIFigure, 'numeric');
            app.XDimEditField.Limits = [0 2000];
            app.XDimEditField.ValueDisplayFormat = '%.0f';
            app.ydimEditFieldLabel = uilabel(app.UIFigure);
            app.ydimEditFieldLabel.Text = 'y dim';
            app.YDimEditField = uieditfield(app.UIFigure, 'numeric');
            app.YDimEditField.Limits = [0 2000];
            app.YDimEditField.ValueDisplayFormat = '%.0f';
            app.ApplynewdimensionsButton = uibutton(app.UIFigure, 'push');
            app.ApplynewdimensionsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplynewdimensionsButtonPushed, true);
            app.ApplynewdimensionsButton.Text = 'Apply new dimensions';
            app.ViewButtonGroup = uibuttongroup(app.UIFigure);
            app.ViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewButtonGroupSelectionChanged, true);
            app.ViewButtonGroup.Title = 'View';
            app.RGBButton = uiradiobutton(app.ViewButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.RGBButton.Value = true;
            app.EdgeButton = uiradiobutton(app.ViewButtonGroup);
            app.EdgeButton.Text = 'Edge';
            app.EdgeLineSegButton = uiradiobutton(app.ViewButtonGroup);
            app.EdgeLineSegButton.Text = 'Edge + Line Seg.';
            app.MaxhoughpeaksEditFieldLabel = uilabel(app.UIFigure);
            app.MaxhoughpeaksEditFieldLabel.Text = 'Max hough peaks';
            app.MaxhoughpeaksEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxhoughpeaksEditField.Limits = [1 300];
            app.MaxhoughpeaksEditField.ValueDisplayFormat = '%.0f';
            app.MaxhoughpeaksEditField.ValueChangedFcn = createCallbackFcn(app, @MaxhoughpeaksEditFieldValueChanged, true);
            app.MaxhoughpeaksEditField.Value = 100;
            app.InPlaceGraphAxes = uiaxes(app.UIFigure);
            title(app.InPlaceGraphAxes, 'In Place Graph')
            xlabel(app.InPlaceGraphAxes, '')
            ylabel(app.InPlaceGraphAxes, '')
            app.nBinsEditFieldLabel = uilabel(app.UIFigure);
            app.nBinsEditFieldLabel.Text = 'nBins';
            app.NBinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NBinsEditField.Limits = [2 200];
            app.NBinsEditField.ValueDisplayFormat = '%.0f';
            app.NBinsEditField.ValueChangedFcn = createCallbackFcn(app, @NBinsEditFieldValueChanged, true);
            app.NBinsEditField.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = houghViz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end